classdef simple_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        uipanel4        matlab.ui.container.Panel
        uipanel3        matlab.ui.container.Panel
        edit1           matlab.ui.control.EditField
        popupmenu6      matlab.ui.control.DropDown
        popupmenu5      matlab.ui.control.DropDown
        popupmenu4      matlab.ui.control.DropDown
        checkbox3       matlab.ui.control.CheckBox
        checkbox2       matlab.ui.control.CheckBox
        checkbox1       matlab.ui.control.CheckBox
        text6           matlab.ui.control.Label
        text7           matlab.ui.control.Label
        upButton        matlab.ui.control.Button
        downButton      matlab.ui.control.Button
        uibuttongroup1  matlab.ui.container.ButtonGroup
        radiobutton4    matlab.ui.control.RadioButton
        radiobutton1    matlab.ui.control.RadioButton
        radiobutton5    matlab.ui.control.RadioButton
        uipanel1        matlab.ui.container.Panel
        popupmenu1      matlab.ui.control.DropDown
        uipanel5        matlab.ui.container.Panel
        text8           matlab.ui.control.Label
        text9           matlab.ui.control.Label
        text10          matlab.ui.control.Label
        text11          matlab.ui.control.Label
        text12          matlab.ui.control.Label
        checkbox4       matlab.ui.control.CheckBox
        uipanel3_2      matlab.ui.container.Panel
        edit1_2         matlab.ui.control.EditField
        popupmenu6_2    matlab.ui.control.DropDown
        popupmenu5_2    matlab.ui.control.DropDown
        popupmenu4_2    matlab.ui.control.DropDown
        checkbox3_2     matlab.ui.control.CheckBox
        checkbox2_2     matlab.ui.control.CheckBox
        checkbox1_2     matlab.ui.control.CheckBox
        text6_2         matlab.ui.control.Label
        text7_2         matlab.ui.control.Label
        upButton_2      matlab.ui.control.Button
        downButton_2    matlab.ui.control.Button
        Label           matlab.ui.control.Label
        TextArea        matlab.ui.control.TextArea
        axes3           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function listbox1_Callback(app, hObject, eventdata, handles)
            % hObject    handle to listbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox1
        end
        
        function listbox1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function simple_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            handles.output = hObject;
            % pcshow([1,1,1],'markersize',500);
            % hold on
            % pcshow([100,100,100],'markersize',500);
             
            cla
            Draw_traj(handles,app)
            if(get(handles.uibuttongroup1,'SelectedObject').String=="Robot view")
                view(45,45)
            end
            if(get(handles.uibuttongroup1,'SelectedObject').String=="Side view")
                view(-45,45)
            end
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox1
            if(get(handles.checkbox1,'Value'))
                set(handles.popupmenu4,'Enable','on');
            else
                set(handles.popupmenu4,'Enable','off');
            end
            [az,el] = view;
            cla
            Draw_traj(handles,app)
            view(az,el);
        end

        % Value changed function: checkbox2
        function checkbox2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox2
            if(get(handles.checkbox2,'Value'))
                set(handles.popupmenu5,'Enable','on');
            else
                set(handles.popupmenu5,'Enable','off');
            end
            [az,el] = view;
            cla
            Draw_traj(handles,app)
            view(az,el);
        end

        % Value changed function: checkbox3
        function checkbox3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox3
            if(get(handles.checkbox3,'Value'))
                set(handles.popupmenu6,'Enable','on');
                set(handles.edit1,'Enable','on');
                set(app.downButton,'Enable','on');
                set(app.upButton,'Enable','on');
            else
                set(handles.popupmenu6,'Enable','off');
                set(handles.edit1,'Enable','off');
                set(app.downButton,'Enable','off');
                set(app.upButton,'Enable','off');
            end
            [az,el] = view;
            cla
            Draw_traj(handles,app)
            view(az,el);
        end

        % Value changed function: checkbox4
        function checkbox4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox4
            [az,el] = view;
            cla
            Draw_traj(handles,app)
            view(az,el);
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit1 as text
            %        str2double(get(hObject,'String')) returns contents of edit1 as a double
            [az,el] = view;
            cla
            Draw_traj(handles,app)
            view(az,el);
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu1
            [az,el] = view;
            cla
            Draw_traj(handles,app)
            view(az,el);
            % --- Executes during object creation, after setting all properties.
        end

        % Value changed function: popupmenu4
        function popupmenu4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu4 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu4
            [az,el] = view;
            cla
            Draw_traj(handles,app)
            view(az,el);
        end

        % Value changed function: popupmenu5
        function popupmenu5_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu5 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu5
            [az,el] = view;
            cla
            Draw_traj(handles,app)
            view(az,el);
        end

        % Value changed function: popupmenu6
        function popupmenu6_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu6 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu6
            [az,el] = view;
            cla
            Draw_traj(handles,app)
            view(az,el);
        end

        % Selection changed function: uibuttongroup1
        function uibuttongroup1_SelectionChangedFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            cla
            Draw_traj(handles,app)
            if(get(handles.uibuttongroup1,'SelectedObject').String=="Robot view")
                view(0,45)
            end
            if(get(handles.uibuttongroup1,'SelectedObject').String=="Side view")
                view(0,45)
            end
        end

        % Button pushed function: upButton
        function upButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            few_num =  str2num(get(handles.edit1,'String'));
            few_num = few_num+1;
            set(handles.edit1,'String',string(few_num));
            %a = {app.figure1.CurrentAxes.CameraPosition,app.figure1.CurrentAxes.CameraTarget,app.figure1.CurrentAxes.CameraViewAngle,app.figure1.CurrentAxes.View,app.figure1.CurrentAxes.PlotBoxAspectRatio};
            [az,el] = view;
            cla
            Draw_traj(handles,app)
            %view([az,el]);
        end

        % Button pushed function: downButton
        function downButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            few_num =  str2num(get(handles.edit1,'String'));
            few_num = few_num-1;
            set(handles.edit1,'String',string(few_num));
            [az,el] = view;
            cla
            Draw_traj(handles,app)
            %view([az,el]);
        end

        % Value changed function: checkbox1_2
        function checkbox1_2ValueChanged(app, event)
            value = app.checkbox1_2.Value;
            if(value)
                set(app.popupmenu4_2,'Enable','on');
            else
                set(app.popupmenu4_2,'Enable','off');
            end
        end

        % Value changed function: checkbox2_2
        function checkbox2_2ValueChanged(app, event)
            value = app.checkbox2_2.Value;
            if(value)
                set(app.popupmenu5_2,'Enable','on');
            else
                set(app.popupmenu5_2,'Enable','off');
            end
        end

        % Value changed function: checkbox3_2
        function checkbox3_2ValueChanged(app, event)
            value = app.checkbox3_2.Value;
            if(value)
                set(app.popupmenu6_2,'Enable','on');
                set(app.downButton_2,'Enable','on');
                set(app.edit1_2,'Enable','on');
                set(app.upButton_2,'Enable','on');
            else
                set(app.popupmenu6_2,'Enable','off');
                set(app.downButton_2,'Enable','off');
                set(app.edit1_2,'Enable','off');
                set(app.upButton_2,'Enable','off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'simple';
            app.figure1.Tag = 'figure1';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'a';
            app.uipanel4.Tag = 'uipanel4';
            app.uipanel3 = uipanel(app.uipanel4);
            app.uipanel3.Title = 'Components';
            app.uipanel3.Tag = 'uipanel3';
            app.edit1 = uieditfield(app.uipanel3, 'text');
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit1.Tag = 'edit1';
            app.edit1.Value = '2';
            app.popupmenu6 = uidropdown(app.uipanel3);
            app.popupmenu6.Items = {'red', 'green', 'blue', 'white', 'cyan'};
            app.popupmenu6.ValueChangedFcn = createCallbackFcn(app, @popupmenu6_Callback, true);
            app.popupmenu6.Tag = 'popupmenu6';
            app.popupmenu6.Value = 'blue';
            app.popupmenu5 = uidropdown(app.uipanel3);
            app.popupmenu5.Items = {'red', 'green', 'blue', 'white', 'cyan'};
            app.popupmenu5.ValueChangedFcn = createCallbackFcn(app, @popupmenu5_Callback, true);
            app.popupmenu5.Tag = 'popupmenu5';
            app.popupmenu5.Value = 'red';
            app.popupmenu4 = uidropdown(app.uipanel3);
            app.popupmenu4.Items = {'red', 'green', 'blue', 'white', 'cyan'};
            app.popupmenu4.ValueChangedFcn = createCallbackFcn(app, @popupmenu4_Callback, true);
            app.popupmenu4.Tag = 'popupmenu4';
            app.popupmenu4.Value = 'green';
            app.checkbox3 = uicheckbox(app.uipanel3);
            app.checkbox3.ValueChangedFcn = createCallbackFcn(app, @checkbox3_Callback, true);
            app.checkbox3.Tag = 'checkbox3';
            app.checkbox3.Text = 'sampled points trajectory';
            app.checkbox3.Value = true;
            app.checkbox2 = uicheckbox(app.uipanel3);
            app.checkbox2.ValueChangedFcn = createCallbackFcn(app, @checkbox2_Callback, true);
            app.checkbox2.Tag = 'checkbox2';
            app.checkbox2.Text = 'Regression GT';
            app.checkbox2.Value = true;
            app.checkbox1 = uicheckbox(app.uipanel3);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Tag = 'checkbox1';
            app.checkbox1.Text = '3D points';
            app.checkbox1.Value = true;
            app.text6 = uilabel(app.uipanel3);
            app.text6.Tag = 'text6';
            app.text6.Text = 'max = ';
            app.text7 = uilabel(app.uipanel3);
            app.text7.Tag = 'text7';
            app.text7.Text = 'min = 2';
            app.upButton = uibutton(app.uipanel3, 'push');
            app.upButton.ButtonPushedFcn = createCallbackFcn(app, @upButtonPushed, true);
            app.upButton.Text = 'up';
            app.downButton = uibutton(app.uipanel3, 'push');
            app.downButton.ButtonPushedFcn = createCallbackFcn(app, @downButtonPushed, true);
            app.downButton.Text = 'down';
            app.uibuttongroup1 = uibuttongroup(app.uipanel4);
            app.uibuttongroup1.SelectionChangedFcn = createCallbackFcn(app, @uibuttongroup1_SelectionChangedFcn, true);
            app.uibuttongroup1.Title = 'Views';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.radiobutton4 = uiradiobutton(app.uibuttongroup1);
            app.radiobutton4.Tag = 'radiobutton4';
            app.radiobutton4.Text = 'Side view';
            app.radiobutton1 = uiradiobutton(app.uibuttongroup1);
            app.radiobutton1.Tag = 'radiobutton1';
            app.radiobutton1.Text = 'Robot view';
            app.radiobutton1.Value = true;
            app.radiobutton5 = uiradiobutton(app.uibuttongroup1);
            app.radiobutton5.Tag = 'radiobutton5';
            app.radiobutton5.Enable = 'off';
            app.radiobutton5.Text = '1M view';
            app.uipanel1 = uipanel(app.uipanel4);
            app.uipanel1.Title = 'Dataset';
            app.uipanel1.Tag = 'uipanel1';
            app.popupmenu1 = uidropdown(app.uipanel1);
            app.popupmenu1.Items = {'3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = '3';
            app.uipanel5 = uipanel(app.uipanel4);
            app.uipanel5.Title = 'RMSE';
            app.uipanel5.Tag = 'uipanel5';
            app.text8 = uilabel(app.uipanel5);
            app.text8.Tag = 'text8';
            app.text8.Text = 'RegressionGT';
            app.text9 = uilabel(app.uipanel5);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Sampled points trajectory';
            app.text10 = uilabel(app.uipanel5);
            app.text10.Tag = 'text10';
            app.text10.Text = '0';
            app.text11 = uilabel(app.uipanel5);
            app.text11.Tag = 'text11';
            app.text11.Text = '0';
            app.text12 = uilabel(app.uipanel5);
            app.text12.Tag = 'text12';
            app.text12.Text = '0';
            app.checkbox4 = uicheckbox(app.uipanel5);
            app.checkbox4.ValueChangedFcn = createCallbackFcn(app, @checkbox4_Callback, true);
            app.checkbox4.Tag = 'checkbox4';
            app.checkbox4.Text = 'cm';
            app.uipanel3_2 = uipanel(app.uipanel4);
            app.uipanel3_2.Title = 'Projection from - Side camera';
            app.uipanel3_2.Tag = 'uipanel3';
            app.edit1_2 = uieditfield(app.uipanel3_2, 'text');
            app.edit1_2.Tag = 'edit1';
            app.edit1_2.Value = '2';
            app.popupmenu6_2 = uidropdown(app.uipanel3_2);
            app.popupmenu6_2.Items = {'red', 'green', 'blue', 'white', 'cyan'};
            app.popupmenu6_2.Tag = 'popupmenu6';
            app.popupmenu6_2.Value = 'blue';
            app.popupmenu5_2 = uidropdown(app.uipanel3_2);
            app.popupmenu5_2.Items = {'red', 'green', 'blue', 'white', 'cyan'};
            app.popupmenu5_2.Tag = 'popupmenu5';
            app.popupmenu5_2.Value = 'red';
            app.popupmenu4_2 = uidropdown(app.uipanel3_2);
            app.popupmenu4_2.Items = {'red', 'green', 'blue', 'white', 'cyan'};
            app.popupmenu4_2.Tag = 'popupmenu4';
            app.popupmenu4_2.Value = 'green';
            app.checkbox3_2 = uicheckbox(app.uipanel3_2);
            app.checkbox3_2.ValueChangedFcn = createCallbackFcn(app, @checkbox3_2ValueChanged, true);
            app.checkbox3_2.Tag = 'checkbox3';
            app.checkbox3_2.Text = 'sampled points trajectory';
            app.checkbox3_2.Value = true;
            app.checkbox2_2 = uicheckbox(app.uipanel3_2);
            app.checkbox2_2.ValueChangedFcn = createCallbackFcn(app, @checkbox2_2ValueChanged, true);
            app.checkbox2_2.Tag = 'checkbox2';
            app.checkbox2_2.Text = 'Regression GT';
            app.checkbox2_2.Value = true;
            app.checkbox1_2 = uicheckbox(app.uipanel3_2);
            app.checkbox1_2.ValueChangedFcn = createCallbackFcn(app, @checkbox1_2ValueChanged, true);
            app.checkbox1_2.Tag = 'checkbox1';
            app.checkbox1_2.Text = '3D points';
            app.checkbox1_2.Value = true;
            app.text6_2 = uilabel(app.uipanel3_2);
            app.text6_2.Tag = 'text6';
            app.text6_2.Text = 'max = ';
            app.text7_2 = uilabel(app.uipanel3_2);
            app.text7_2.Tag = 'text7';
            app.text7_2.Text = 'min = 2';
            app.upButton_2 = uibutton(app.uipanel3_2, 'push');
            app.upButton_2.Text = 'up';
            app.downButton_2 = uibutton(app.uipanel3_2, 'push');
            app.downButton_2.Text = 'down';
            app.Label = uilabel(app.uipanel3_2);
            app.Label.Text = '미구현';
            app.axes3 = uiaxes(app.uipanel4);
            app.axes3.Tag = 'axes3';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simple_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)simple_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end